classdef GasGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        ApproximationDropDownLabel     matlab.ui.control.Label
        ApproximationDropDown          matlab.ui.control.DropDown
        SelectGasDropDownLabel         matlab.ui.control.Label
        SelectGasDropDown              matlab.ui.control.DropDown
        AbsorptionDropDownLabel        matlab.ui.control.Label
        AbsorptionDropDown             matlab.ui.control.DropDown
        PlotButton                     matlab.ui.control.Button
        ClearPlotButton                matlab.ui.control.Button
        SumCurrentPlotsButton          matlab.ui.control.Button
        ConcentrationEditFieldLabel    matlab.ui.control.Label
        ConcentrationEditField         matlab.ui.control.NumericEditField
        FrequencyStartcm1EditFieldLabel  matlab.ui.control.Label
        FrequencyStartcm1EditField     matlab.ui.control.NumericEditField
        FrequencyEndcm1EditFieldLabel  matlab.ui.control.Label
        FrequencyEndcm1EditField       matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel     matlab.ui.control.Label
        TemperatureKEditField          matlab.ui.control.NumericEditField
        PathLengthcmEditFieldLabel     matlab.ui.control.Label
        PathLengthcmEditField          matlab.ui.control.NumericEditField
        PressureAtmEditFieldLabel      matlab.ui.control.Label
        PressureAtmEditField           matlab.ui.control.NumericEditField
        WavelengthStartnmEditFieldLabel  matlab.ui.control.Label
        WavelengthStartnmEditField     matlab.ui.control.NumericEditField
        WavelengthEndnmEditFieldLabel  matlab.ui.control.Label
        WavelengthEndnmEditField       matlab.ui.control.NumericEditField
        TemperatureCelsiusEditFieldLabel  matlab.ui.control.Label
        TemperatureCelsiusEditField    matlab.ui.control.NumericEditField
        GUIformodellingMolecularSpectraLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Gases
        data
        tempdata
        numClicks
        currentPlots
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, transitions, gases)
            load ('C:\Users\Blair\Documents\Uni\4thYear\gasTransitions','GasTransitions');
            app.data = GasTransitions;
            app.tempdata = GasTransitions;
            load('gasMap','gases')
            app.Gases = gases;
            app.numClicks = 0;
            app.currentPlots ={};
            app.SelectGasDropDown.Items = keys(app.Gases);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Resizing data to match selected gas
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            gasChoice = app.SelectGasDropDown.Value;
            gasFind = (app.tempdata(:,1)== app.Gases(gasChoice));
            app.tempdata = app.tempdata(gasFind,(1:10));
        
            numIso = max(app.tempdata(:,2));
            isoChoice = str2double(sprintfc('%01d',1:numIso));                     % Isotopologue(s) to be looked at
            isoSize = length(isoChoice);                                           % Size of isoChoice martix
            app.data = cell(1,isoSize);                 
            for n = 1: isoSize
                isoFind = (app.tempdata(:,2 )== isoChoice(n));
                app.data{n} = app.tempdata(isoFind,(1:10));
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Reading in Partion data
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            partitions = cell(1,isoSize);
            for n = 1: length(isoChoice)
            partFilePath = strcat(pwd,'\GasData\',gasChoice,num2str(isoChoice(n)),'.txt');
            fid = fopen(partFilePath);
            formatSpec  = '%4f %16f %*[^\n]';
            st0 = textscan(fid,formatSpec);
            fclose(fid);
            partitions{n} = cell2mat(st0);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Reading in Gas Mass data
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            filePath = strcat(pwd,'\GasData\molparam.txt');
            fid = fopen(filePath);
            g0 = textscan(fid,formatSpec);
            fclose(fid);
            masses = cell2mat(g0);
        
            massFind= (masses == app.Gases(gasChoice));
            masses = masses(massFind,(1:2));
            masses(:,1) = [];
        
            vStart = app.FrequencyStartcm1EditField.Value;
            vEnd = app.FrequencyEndcm1EditField.Value;
            if(vStart>vEnd)
                temp = vEnd;
                vEnd = vStart;
                vStart = temp;
            end
        
            dataSize = zeros (1,isoSize);
            for n = 1: isoSize
            vFind = (app.data{n}(:,3) >= vStart & app.data{n}(:,3) <= vEnd);
            app.data{n} = app.data{n}(vFind,(1:10));
            dataSize(n) = size(app.data{n},1);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Declaring Constants 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            c = 299792458e2;                                        % Speed of light (cm-1)
            h = 6.626e-34;                                          % Planck constant
            k = 1.38064852e-23;                                     % Boltzmann constant
            c2 = (h*c)/k;                                           % Second radiation constant
            T = app.TemperatureKEditField.Value;                    % Temperature of system (Kelvin)
            T0 = 296;                                               % Temperature of system (Kelvin)
            P = app.PressureAtmEditField.Value;                     % Pressure of system (Atmosphere)
            concentration = app.ConcentrationEditField.Value;       % Concentration
            pLength = app.PathLengthcmEditField.Value;              % Length of cell(cm)
            M = masses(isoChoice);                                  % Molecular mass of the selected gas
            step = 1000;                                            % Number of data points in wavelength
            
            if(cellfun(@isempty,app.data))
                uialert(fig,'No absorbance for specified frequency range, please try again','Plot Error'); 
            elseif(T > min(cellfun('size',partitions,1)) || T<1)
                uialert(fig,'Invalid Temperature, please try again','Plot Error')
            else
                    app.ClearPlotButton.Enable = 'on';
                    app.numClicks = guidata(app.UIFigure);
                    app.numClicks = app.numClicks + 1;
                if(app.numClicks >= 2)
                    app.SumCurrentPlotsButton.Enable = 'on';
                end
                guidata(app.UIFigure,app.numClicks);  
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Allocating size of cell arrays and matrices
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [v,v0,S_t0,gammaAir,gammaSelf,pShift,E_lower,gammaG,gammaL,Y] = deal(cell(1,isoSize));
            [Q_tref,Q_t] = deal(zeros(1,isoSize));
            [Q_tref_temp,Q_t_temp] = deal(zeros(1,2,isoSize));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % HITRAN Data  
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for n = 1: isoSize
                v{n} = repmat(linspace(vStart,vEnd,step),dataSize(n),1);
                v0{n} = app.data{n}(:,3);                                           % Transition wavenumber
                S_t0{n} = app.data{n}(:,4);                                         % Line Intensity
                S_t0{n} = (7.339e21.*S_t0{n})./T;                                   % Line Intensity Conversion
                gammaAir{n} = app.data{n}(:,6).*(T0/T).^app.data{n}(:,8);           % Air broadened HWHM 
                gammaSelf{n} = app.data{n}(:,7).*(T0/T).^app.data{n}(:,8);          % Self broadened HWHM
                % n = data(:,8);                                                    % Temperature dependent coefficient for air broadened HWHM(Lorentzian)
                pShift{n} = app.data{n}(:,9);                                       % Pressure Shift induced by air
                E_lower{n} = app.data{n}(:,10);                                     % Lower State Energy
                Q_tref_temp(:,:,n) = partitions{n}(T0,:);
                Q_tref(n) = Q_tref_temp(n*2);                                   
                Q_t_temp(:,:,n) = partitions{n}(T,:);
                Q_t(n) = Q_t_temp(n*2);                                         
                gammaG{n} = (v0{n}.*7.1623e-7.*(T/M(n)).^0.5)';                 % Calculates the Gaussian FWHM
                gammaL{n} = ((2*P).*((concentration.*gammaSelf{n})+ ...         % Calculates the Lorentzian FWHM
                ((1-concentration).*gammaAir{n})))';     
                Y{n} = (gammaL{n}.*sqrt(log(2)))./gammaG{n};                    % Calculating Y for Voigt lineshape  
            end
        
            approxSelect = app.ApproximationDropDown.Value;
            if(strcmp(approxSelect,'Mcleans'))
                voigtFinal = Mcleans(isoSize,dataSize,gammaG,v,v0,P,pShift,Y,S_t0,Q_tref,Q_t,c2,E_lower,T,T0,concentration,pLength);
            elseif(strcmp(approxSelect,'Simple Empirical'))
                voigtFinal = Simple_Empirical(isoSize,dataSize,gammaL,gammaG,v,v0,S_t0,Q_tref,Q_t,c2,E_lower,T,T0,concentration,pLength,P);
            elseif(strcmp(approxSelect,'Kielkopf'))
                voigtFinal = Kielkopf(isoSize,dataSize,gammaL,gammaG,v,v0,S_t0,Q_tref,Q_t,c2,E_lower,T,T0,concentration,pLength,P,pShift);
            end
            plotType = app.AbsorptionDropDown.Value;
            app.UIAxes.XGrid='on'; app.UIAxes.YGrid='on';
            app.UIAxes.Title.String = "Final " + plotType + " for " + gasChoice +...
            " in the range " + vStart + " to " + vEnd + " cm^{-1}";
            app.UIAxes.Title.FontSize = 14;
            app.UIAxes.YLabel.String = 'Absorbance, (I/Io)';
            app.UIAxes.YLabel.FontSize = 11; app.UIAxes.YLabel.FontWeight = 'bold';
            app.UIAxes.XLabel.String = 'Frequency, cm-1';
             app.UIAxes.XLabel.FontSize = 11; app.UIAxes.XLabel.FontWeight = 'bold';
            app.UIAxes.NextPlot = 'add';
            
            isoList = zeros(1,isoSize);
            for n = 1: isoSize
                 if(isempty(voigtFinal{n}))
                     isoList(n) = n;  
                 end
            end
            x = v{1}(1,:);
            approx = voigtFinal(~cellfun('isempty',voigtFinal));
            finalApprox = cell(1,length(approx));
            y = cell(1,length(approx));
            xyz=zeros(1,step);
            for n = 1: length(approx)
                if(size(approx{n})==size(xyz))
                    y{n} = approx{n};
                else
                    finalApprox{n} = sum(approx{n});
                    y{n} = finalApprox{n};
                end
                if(strcmp(plotType,'Transmission'))
                    y{n} = exp(-1.*y{n});
                    app.UIAxes.YLabel.String = 'Transmission (%)';
                end
            end
            setappdata(app.UIFigure,'xaxis',x);
            
            if(length(y)==1)
                out = cell2mat(y);
            else
                out = sum(cell2mat(y'));
            end
            if(app.numClicks == 1)
                app.currentPlots = getappdata(app.UIFigure,'currentPlots');  
            else
                app.currentPlots = getappdata(app.UIFigure,'currentPlot');
            end
            app.currentPlots{end+1} = out;
            setappdata(app.UIFigure,'currentPlot',app.currentPlots);
            legendText = strcat(gasChoice,": ",approxSelect);
            plot(app.UIAxes,x,out,'DisplayName',legendText);  
            legend(app.UIAxes);
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
                cla(app.UIAxes,'reset');
                app.ClearPlotButton.Enable = 'off';
                app.SumCurrentPlotsButton.Enable = 'off';
                app.PlotButton.Enable = 'on';
        end

        % Button pushed function: SumCurrentPlotsButton
        function SumCurrentPlotsButtonPushed(app, event)
            x = getappdata(app.UIFigure,'xaxis');
            out = sum(cell2mat(app.currentPlots'));
            plot(app.UIAxes,x,out,'DisplayName','Current Plot Summation');
            app.currentPlots{end+1} = out;
        end

        % Value changed function: FrequencyStartcm1EditField
        function FrequencyStartcm1EditFieldValueChanged(app, event)
            app.WavelengthStartnmEditField.Value = 1e7/app.FrequencyStartcm1EditField.Value;
        end

        % Value changed function: FrequencyEndcm1EditField
        function FrequencyEndcm1EditFieldValueChanged(app, event)
            app.WavelengthEndnmEditField.Value = 1e7/app.FrequencyEndcm1EditField.Value;
        end

        % Value changed function: WavelengthStartnmEditField
        function WavelengthStartnmEditFieldValueChanged(app, event)
             app.FrequencyStartcm1EditField.Value = 1e7/app.WavelengthStartnmEditField.Value;
        end

        % Value changed function: WavelengthEndnmEditField
        function WavelengthEndnmEditFieldValueChanged(app, event)
            app.FrequencyEndcm1EditField.Value = 1e7/app.WavelengthEndnmEditField.Value;
        end

        % Value changed function: TemperatureCelsiusEditField
        function TemperatureCelsiusEditFieldValueChanged(app, event)
            app.TemperatureKEditField.Value = round(app.TemperatureCelsiusEditField.Value + 273.15);
        end

        % Value changed function: TemperatureKEditField
        function TemperatureKEditFieldValueChanged(app, event)
          app.TemperatureCelsiusEditField.Value = app.TemperatureKEditField.Value - 273.15;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            title = "Help";
            message = sprintf('Select an approximation from the drop-down, then choose your desired gas\nFill in all of the available fields\nSelect a plot type to obtain a final plot\nThere is limits on both the frequency/wavelength range and the temperature');
            f = msgbox(message,title);
            set(f, 'position', [400 400 500 100]);
            th = findall(f, 'Type', 'Text');
            th.FontSize = 12;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            title = "About";
            message = sprintf('Author: Blair McKenzie\nGUI Developed as part of fullfilment of 4th Year individial project\nHITRAN 2016 was used to obtain spectroscopic parameters');
            f = msgbox(message,title);
            set(f, 'position', [400 400 500 100]);
            th = findall(f, 'Type', 'Text');
            th.FontSize = 12;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ApproximationDropDownLabel = uilabel(app.UIFigure);
            app.ApproximationDropDownLabel.Text = 'Approximation';
            app.ApproximationDropDown = uidropdown(app.UIFigure);
            app.ApproximationDropDown.Items = {'Mcleans', 'Simple Empirical', 'Kielkopf'};
            app.ApproximationDropDown.Value = 'Mcleans';
            app.SelectGasDropDownLabel = uilabel(app.UIFigure);
            app.SelectGasDropDownLabel.Text = 'Select Gas';
            app.SelectGasDropDown = uidropdown(app.UIFigure);
            app.SelectGasDropDown.Items = {'Gases'};
            app.SelectGasDropDown.Value = 'Gases';
            app.AbsorptionDropDownLabel = uilabel(app.UIFigure);
            app.AbsorptionDropDownLabel.Text = 'Absorption';
            app.AbsorptionDropDown = uidropdown(app.UIFigure);
            app.AbsorptionDropDown.Items = {'Absorption', 'Transmission'};
            app.AbsorptionDropDown.Value = 'Absorption';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Enable = 'off';
            app.ClearPlotButton.Text = 'Clear Plot';
            app.SumCurrentPlotsButton = uibutton(app.UIFigure, 'push');
            app.SumCurrentPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SumCurrentPlotsButtonPushed, true);
            app.SumCurrentPlotsButton.Enable = 'off';
            app.SumCurrentPlotsButton.Text = 'Sum Current Plots';
            app.ConcentrationEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationEditFieldLabel.Text = 'Concentration';
            app.ConcentrationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyStartcm1EditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyStartcm1EditFieldLabel.Text = 'Frequency Start (cm-1)';
            app.FrequencyStartcm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyStartcm1EditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyStartcm1EditFieldValueChanged, true);
            app.FrequencyEndcm1EditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEndcm1EditFieldLabel.Text = 'Frequency End (cm-1)';
            app.FrequencyEndcm1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEndcm1EditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEndcm1EditFieldValueChanged, true);
            app.TemperatureKEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureKEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureKEditFieldValueChanged, true);
            app.PathLengthcmEditFieldLabel = uilabel(app.UIFigure);
            app.PathLengthcmEditFieldLabel.Text = 'Path Length (cm)';
            app.PathLengthcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureAtmEditFieldLabel = uilabel(app.UIFigure);
            app.PressureAtmEditFieldLabel.Text = 'Pressure (Atm)';
            app.PressureAtmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthStartnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthStartnmEditFieldLabel.Text = 'Wavelength Start (nm)';
            app.WavelengthStartnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthStartnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthStartnmEditFieldValueChanged, true);
            app.WavelengthEndnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthEndnmEditFieldLabel.Text = 'Wavelength End (nm)';
            app.WavelengthEndnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthEndnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEndnmEditFieldValueChanged, true);
            app.TemperatureCelsiusEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureCelsiusEditFieldLabel.Text = 'Temperature (Celsius)';
            app.TemperatureCelsiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureCelsiusEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCelsiusEditFieldValueChanged, true);
            app.GUIformodellingMolecularSpectraLabel = uilabel(app.UIFigure);
            app.GUIformodellingMolecularSpectraLabel.Text = 'GUI for modelling Molecular Spectra';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GasGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end